classdef ThermoCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ThermodynamicsPropertiesCalculator  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainMenu                        matlab.ui.container.Tab
        CalculateSHVCLPropertiesButton  matlab.ui.control.Button
        CalculateSubstancePropertiesButton  matlab.ui.control.Button
        CalculateIdealGasPropertiesButton  matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        ExitButton                      matlab.ui.control.Button
        ThermodynamicsPropertiesCalculatorLabel  matlab.ui.control.Label
        UnknownState                    matlab.ui.container.Tab
        ResultsTextArea                 matlab.ui.control.TextArea
        TextArea_2Label                 matlab.ui.control.Label
        CalculatePropertiesButton       matlab.ui.control.Button
        Value2Field                     matlab.ui.control.NumericEditField
        ValueLabel_2                    matlab.ui.control.Label
        Property2DropDown               matlab.ui.control.DropDown
        SelectProperty2Label            matlab.ui.control.Label
        Value1Field                     matlab.ui.control.NumericEditField
        ValueLabel                      matlab.ui.control.Label
        Property1DropDown               matlab.ui.control.DropDown
        SelectProperty1Label            matlab.ui.control.Label
        SubstanceDropDown               matlab.ui.control.DropDown
        SelectSubstanceLabel            matlab.ui.control.Label
        UnknownStatePropertiesCalculatorLabel  matlab.ui.control.Label
        IdealGas                        matlab.ui.container.Tab
        IGResultsTextArea               matlab.ui.control.TextArea
        TextArea2Label                  matlab.ui.control.Label
        CalculatePropertiesButton_2     matlab.ui.control.Button
        IGTempField                     matlab.ui.control.NumericEditField
        TemperatureKLabel               matlab.ui.control.Label
        IGSubstanceDropDown             matlab.ui.control.DropDown
        SelectSubstanceLabel_2          matlab.ui.control.Label
        IdealGasPropertiesCalculatorLabel  matlab.ui.control.Label
        SHVCLPropertiesTab              matlab.ui.container.Tab
        StateDropDown                   matlab.ui.control.DropDown
        SelectStateLabel                matlab.ui.control.Label
        SHVCLResultsTextArea            matlab.ui.control.TextArea
        TextArea3Label                  matlab.ui.control.Label
        CalculatePropertiesButton_3     matlab.ui.control.Button
        SHVCLPressField                 matlab.ui.control.NumericEditField
        PressurebarLabel                matlab.ui.control.Label
        SHVCLTempField                  matlab.ui.control.NumericEditField
        TemperatureCLabel               matlab.ui.control.Label
        SHVCLSubstanceDropDown          matlab.ui.control.DropDown
        SelectSubstanceLabel_3          matlab.ui.control.Label
        SHVCLPropertiesCalculatorLabel  matlab.ui.control.Label
    end

methods (Access = private)
    % Helper function to convert property display names to internal names
    function propName = convertToPropertyName(app, displayName)
        switch displayName
            case 'Temperature (C)'
                propName = 'temperature';
            case 'Pressure (bar)'
                propName = 'pressure';
            case 'Specific Volume (m^3/kg)'
                propName = 'specific volume';
            case 'Internal Energy (kJ/kg)'
                propName = 'internal energy';
            case 'Enthalpy (kJ/kg)'
                propName = 'enthalpy';
            case 'Entropy (kJ/kg/K)'
                propName = 'entropy';
        end
    end

    % Helper function to convert substance display names to internal names
    function substanceName = convertToSubstanceName(app, displayName)
        switch displayName
            case 'Water'
                substanceName = 'water';
            case 'R-134a'
                substanceName = 'r134a';
            case 'Ammonia'
                substanceName = 'ammonia';
            case 'Propane'
                substanceName = 'propane';
            case 'CO2'
                substanceName = 'co2';
            otherwise
                substanceName = lower(displayName);
        end
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Value1Field
        function Value1FieldValueChanged(app, event)
            % Get selected value from first dropdown
            selectedProp = app.Property1DropDown.Value;
            
            % Update Property2 options based on Property1 selection
            if strcmp(selectedProp, 'Temperature (C)')
                app.Property2DropDown.Items = {'Pressure (bar)', 'Specific Volume (m^3/kg)', ...
                    'Internal Energy (kJ/kg)', 'Enthalpy (kJ/kg)', 'Entropy (kJ/kg/K)'};
            else  % when pressure is selected
                app.Property2DropDown.Items = {'Temperature (C)', 'Specific Volume (m^3/kg)', ...
                    'Internal Energy (kJ/kg)', 'Enthalpy (kJ/kg)', 'Entropy (kJ/kg/K)'};
            end
            
            % Reset second dropdown to first item in list
            app.Property2DropDown.Value = app.Property2DropDown.Items{1};
            
        end

        % Button pushed function: CalculateSubstancePropertiesButton
        function CalculateSubstancePropertiesButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.UnknownState;
        end

        % Button pushed function: CalculateIdealGasPropertiesButton
        function CalculateIdealGasPropertiesButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.IdealGas;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app)

        end

        % Button pushed function: CalculatePropertiesButton
        function CalculatePropertiesButtonPushed(app, event)
        try
            % Get values and convert them appropriately
            substance = app.convertToSubstanceName(app.SubstanceDropDown.Value);
            
            % For property 1
            prop1_display = app.Property1DropDown.Value;
            if strcmp(prop1_display, 'Temperature (C)')
                prop1 = 'temperature';
            else
                prop1 = 'pressure';
            end
            prop1_value = app.Value1Field.Value;
            
            % For property 2
            prop2_display = app.Property2DropDown.Value;
            prop2 = app.convertToPropertyName(prop2_display);
            prop2_value = app.Value2Field.Value;
        
            % Get table and state
            table = getSubstanceTable(substance, prop1);
            state = determine_state(table, prop1, prop1_value, prop2, prop2_value);
            
            % Process based on state
            switch state
                case 'SL'
                    properties = saturatedLookup(table, prop1, prop1_value, state);
                    % Create output string
                    output = sprintf('Results for %s (State: %s)\n', upper(substance), state);
                    output = [output repmat('-', 1, 50) '\n'];
                    if isfield(properties, 'interpolated') && properties.interpolated
                        output = [output '** Values obtained through interpolation **\n\n'];
                    end
                    output = [output sprintf('Temperature: %.2f °C\n', properties.temperature)];
                    output = [output sprintf('Pressure: %.2f bar\n', properties.pressure)];
                    output = [output sprintf('Specific Volume: %.7 m³/kg\n', properties.specific_volume)];
                    output = [output sprintf('Internal Energy: %.2f kJ/kg\n', properties.internal_energy)];
                    output = [output sprintf('Enthalpy: %.2f kJ/kg\n', properties.enthalpy)];
                    output = [output sprintf('Entropy: %.4f kJ/kg·K\n', properties.entropy)];
                    app.ResultsTextArea.Value = output;
                    
                case 'SV'
                    properties = saturatedLookup(table, prop1, prop1_value, state);
                    % Create output string
                    output = sprintf('Results for %s (State: %s)\n', upper(substance), state);
                    output = [output repmat('-', 1, 50) '\n'];
                    if isfield(properties, 'interpolated') && properties.interpolated
                        output = [output '** Values obtained through interpolation **\n\n'];
                    end
                    output = [output sprintf('Temperature: %.2f °C\n', properties.temperature)];
                    output = [output sprintf('Pressure: %.2f bar\n', properties.pressure)];
                    output = [output sprintf('Specific Volume: %.7f m³/kg\n', properties.specific_volume)];
                    output = [output sprintf('Internal Energy: %.2f kJ/kg\n', properties.internal_energy)];
                    output = [output sprintf('Enthalpy: %.2f kJ/kg\n', properties.enthalpy)];
                    output = [output sprintf('Entropy: %.4f kJ/kg·K\n', properties.entropy)];
                    app.ResultsTextArea.Value = output;
                    
                case 'SHV'
                    msg = "This substance is in SHV state. Would you like to calculate properties using temperature and pressure?";
                    response = questdlg(msg, 'Superheated Vapor State', 'Yes', 'No', 'Yes');
                    if strcmp(response, 'Yes')
                        % Switch to SHV/CL tab
                        app.TabGroup.SelectedTab = app.SHVCLPropertiesTab;
                        % Pre-fill the substance
                        app.SHVCLSubstanceDropDown.Value = app.SubstanceDropDown.Value;
                        % Set state dropdown
                        app.StateDropDown.Value = 'Superheated Vapor (SHV)';
                        % Pre-fill temperature or pressure if we have it
                        if strcmp(prop1, 'temperature')
                            app.SHVCLTempField.Value = prop1_value;
                        elseif strcmp(prop1, 'pressure')
                            app.SHVCLPressField.Value = prop1_value;
                        end
                    end
                    return;
                    
                case 'CL'
                    msg = "This substance is in CL state. Would you like to calculate properties using temperature and pressure?";
                    response = questdlg(msg, 'Compressed Liquid State', 'Yes', 'No', 'Yes');
                    if strcmp(response, 'Yes')
                        % Switch to SHV/CL tab
                        app.TabGroup.SelectedTab = app.SHVCLPropertiesTab;
                        % Pre-fill the substance
                        app.SHVCLSubstanceDropDown.Value = app.SubstanceDropDown.Value;
                        % Set state dropdown
                        app.StateDropDown.Value = 'Compressed Liquid (CL)';
                        % Pre-fill temperature or pressure if we have it
                        if strcmp(prop1, 'temperature')
                            app.SHVCLTempField.Value = prop1_value;
                        elseif strcmp(prop1, 'pressure')
                            app.SHVCLPressField.Value = prop1_value;
                        end
                    end
                    return;
                    
                otherwise  % SLVM case
                    properties = mixtureLookup(table, prop1, prop1_value, prop2, prop2_value);
                    % Create output string
                    output = sprintf('Results for %s (State: %s)\n\n', upper(substance), state);
                    if isfield(properties, 'interpolated') && properties.interpolated
                        output = [output '** Values obtained through interpolation. **\n\n'];
                    end
                    output = [output sprintf('Temperature: %14.2f °C\n', properties.temperature)];
                    output = [output sprintf('Pressure: %.2f bar\n', properties.pressure)];
                    output = [output sprintf('Specific Volume: %.7f m³/kg\n', properties.specific_volume)];
                    output = [output sprintf('Internal Energy: %.2f kJ/kg\n', properties.internal_energy)];
                    output = [output sprintf('Enthalpy: %.2f kJ/kg\n', properties.enthalpy)];
                    output = [output sprintf('Entropy: %.4f kJ/kg·K\n', properties.entropy)];
                    if isfield(properties, 'quality')
                        output = [output sprintf('Quality: %.4f\n', properties.quality)];
                    end
                    app.ResultsTextArea.Value = output;
            end
            
        catch ME
            app.ResultsTextArea.Value = sprintf('Error: %s', ME.message);
            fprintf('Error in calculation: %s\n', ME.message);
        end
        end

        % Button pushed function: CalculatePropertiesButton_2
        function CalculatePropertiesButton_2Pushed(app, event)
            try
                % Get values from inputs
                substance = app.IGSubstanceDropDown.Value;
                temp_K = app.IGTempField.Value;
                
                % Calculate properties
                properties = getIdealGasProperties(substance, temp_K);
                
                % Create output string
                output = sprintf('Ideal Gas Properties for %s\n\n', substance);
                
                % Add interpolation warning if present
                if isfield(properties, 'interpolated') && properties.interpolated
                    output = [output '** Values obtained through interpolation **\n\n'];
                end
                
                % Display properties
                output = [output sprintf('Temperature: %.2f K\n', properties.temperature)];
                output = [output sprintf('Enthalpy: %.4f kJ/kg\n', properties.enthalpy)];
                output = [output sprintf('Internal Energy: %.4f kJ/kg\n', properties.internal_energy)];
                output = [output sprintf('Entropy: %.4f kJ/kg·K\n', properties.entropy)];
                
                % Check if we have pressure ratio and volume ratio
                if isfield(properties, 'pr')
                    output = [output sprintf('Pressure Ratio: %.4f\n', properties.pr)];
                end
                if isfield(properties, 'vr')
                    output = [output sprintf('Volume Ratio: %.4f\n', properties.vr)];
                end
                
                app.IGResultsTextArea.Value = output;
                
            catch ME
                app.IGResultsTextArea.Value = sprintf('Error: %s', ME.message);
                fprintf('Error in calculation: %s\n', ME.message);
            end
        end

        % Button pushed function: CalculatePropertiesButton_3
        function CalculatePropertiesButton_3Pushed(app, event)
    try
        % Get inputs
        substance = app.convertToSubstanceName(app.SHVCLSubstanceDropDown.Value);
        temp = app.SHVCLTempField.Value;
        press = app.SHVCLPressField.Value;
        
        % Get state from dropdown
        stateSelection = app.StateDropDown.Value;
        if strcmp(stateSelection, 'Superheated Vapor (SHV)')
            state = 'SHV';
        else
            state = 'CL';
        end
        
        % Calculate properties
        properties = calculateSHVCLProperties(substance, state, temp, press);
        
        % Create output string
        output = sprintf('Results for %s (State: %s)\n\n', upper(substance), state);
        
        % Add interpolation warning if present
        if isfield(properties, 'interpolated') && properties.interpolated
            output = [output '** Values obtained through interpolation **\n\n'];
        end
        
        % Display all properties
        output = [output sprintf('Temperature: %.2f °C\n', properties.temperature)];
        output = [output sprintf('Pressure: %.2f bar\n', properties.pressure)];
        output = [output sprintf('Specific Volume: %.7f m³/kg\n', properties.specific_volume)];
        output = [output sprintf('Internal Energy: %.2f kJ/kg\n', properties.internal_energy)];
        output = [output sprintf('Enthalpy: %.2f kJ/kg\n', properties.enthalpy)];
        output = [output sprintf('Entropy: %.4f kJ/kg·K\n', properties.entropy)];
        
        app.SHVCLResultsTextArea.Value = output;
        
    catch ME
        app.SHVCLResultsTextArea.Value = sprintf('Error: %s', ME.message);
        fprintf('Error in calculation: %s\n', ME.message);
    end
        end

        % Button pushed function: CalculateSHVCLPropertiesButton
        function CalculateSHVCLPropertiesButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SHVCLPropertiesTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ThermodynamicsPropertiesCalculator = uifigure('Visible', 'off');
            app.ThermodynamicsPropertiesCalculator.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.ThermodynamicsPropertiesCalculator);
            app.MainMenu = uitab(app.TabGroup);
            app.MainMenu.Title = 'Home';
            app.ThermodynamicsPropertiesCalculatorLabel = uilabel(app.MainMenu);
            app.ThermodynamicsPropertiesCalculatorLabel.Text = 'Thermodynamics Properties Calculator';
            app.ExitButton = uibutton(app.MainMenu, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.TextArea = uitextarea(app.MainMenu);
            app.TextArea.Value = {'This Thermodynamics Property Calculator was developed by Ayush Karkare and is currently a work in progress. If you come across any bugs or have suggestions for improvements, please feel free to reach out to me at akarkare@purdue.edu.'; ''; 'The calculator is based on the thermodynamic property tables from ME200 (Fall 2024). I recommend using this tool only after the first midterm, so you have a solid understanding of how to navigate and interpret the property tables yourself. When this calculator interpolates a warning will be thrown so that you can go back and write the calculations or indicate that you interpolated a value.'; ''; 'In the future, I plan to add more features, including: '; ''; 'Generating P-v, T-v, and T-s diagrams.'; 'An equation tab to help understand key thermodynamic relationships.'; 'And additional functionality to enhance the overall experience.'; 'This calculator was built using MATLAB and its App Designer platform, with the goal of making thermodynamic property calculations more intuitive and accessible.'};
            app.CalculateIdealGasPropertiesButton = uibutton(app.MainMenu, 'push');
            app.CalculateIdealGasPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateIdealGasPropertiesButtonPushed, true);
            app.CalculateIdealGasPropertiesButton.Text = 'Calculate Ideal Gas Properties';
            app.CalculateSubstancePropertiesButton = uibutton(app.MainMenu, 'push');
            app.CalculateSubstancePropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSubstancePropertiesButtonPushed, true);
            app.CalculateSubstancePropertiesButton.Text = 'Calculate Substance Properties';
            app.CalculateSHVCLPropertiesButton = uibutton(app.MainMenu, 'push');
            app.CalculateSHVCLPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSHVCLPropertiesButtonPushed, true);
            app.CalculateSHVCLPropertiesButton.Text = 'Calculate SHV/CL Properties';
            app.UnknownState = uitab(app.TabGroup);
            app.UnknownState.Title = 'Unknown State Properties';
            app.UnknownStatePropertiesCalculatorLabel = uilabel(app.UnknownState);
            app.UnknownStatePropertiesCalculatorLabel.Text = 'Unknown State Properties Calculator';
            app.SelectSubstanceLabel = uilabel(app.UnknownState);
            app.SelectSubstanceLabel.Text = 'Select Substance:';
            app.SubstanceDropDown = uidropdown(app.UnknownState);
            app.SubstanceDropDown.Items = {'Water', 'R-134a', 'Ammonia', 'Propane', 'CO2 '};
            app.SubstanceDropDown.Value = 'Water';
            app.SelectProperty1Label = uilabel(app.UnknownState);
            app.SelectProperty1Label.Text = 'Select Property 1:';
            app.Property1DropDown = uidropdown(app.UnknownState);
            app.Property1DropDown.Items = {'Temperature (C)', 'Pressure (bar)'};
            app.Property1DropDown.Value = 'Temperature (C)';
            app.ValueLabel = uilabel(app.UnknownState);
            app.ValueLabel.Text = 'Value: ';
            app.Value1Field = uieditfield(app.UnknownState, 'numeric');
            app.Value1Field.ValueChangedFcn = createCallbackFcn(app, @Value1FieldValueChanged, true);
            app.SelectProperty2Label = uilabel(app.UnknownState);
            app.SelectProperty2Label.Text = 'Select Property 2:';
            app.Property2DropDown = uidropdown(app.UnknownState);
            app.Property2DropDown.Items = {'Pressure (bar)', 'Specific Volume (m^3/kg)', 'Internal Energy (kJ/kg)', 'Enthalpy (kJ/kg)', 'Entropy (kJ/kg/K)'};
            app.Property2DropDown.Value = 'Pressure (bar)';
            app.ValueLabel_2 = uilabel(app.UnknownState);
            app.ValueLabel_2.Text = 'Value: ';
            app.Value2Field = uieditfield(app.UnknownState, 'numeric');
            app.CalculatePropertiesButton = uibutton(app.UnknownState, 'push');
            app.CalculatePropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePropertiesButtonPushed, true);
            app.CalculatePropertiesButton.Text = 'Calculate Properties';
            app.TextArea_2Label = uilabel(app.UnknownState);
            app.TextArea_2Label.Text = 'Text Area';
            app.ResultsTextArea = uitextarea(app.UnknownState);
            app.IdealGas = uitab(app.TabGroup);
            app.IdealGas.Title = 'Ideal Gas Properties';
            app.IdealGasPropertiesCalculatorLabel = uilabel(app.IdealGas);
            app.IdealGasPropertiesCalculatorLabel.Text = 'Ideal Gas Properties Calculator';
            app.SelectSubstanceLabel_2 = uilabel(app.IdealGas);
            app.SelectSubstanceLabel_2.Text = 'Select Substance: ';
            app.IGSubstanceDropDown = uidropdown(app.IdealGas);
            app.IGSubstanceDropDown.Items = {'Air', 'CO2', 'H2O', 'O2', 'N2', 'NH3'};
            app.IGSubstanceDropDown.Value = 'Air';
            app.TemperatureKLabel = uilabel(app.IdealGas);
            app.TemperatureKLabel.Text = 'Temperature (K):';
            app.IGTempField = uieditfield(app.IdealGas, 'numeric');
            app.CalculatePropertiesButton_2 = uibutton(app.IdealGas, 'push');
            app.CalculatePropertiesButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculatePropertiesButton_2Pushed, true);
            app.CalculatePropertiesButton_2.Text = 'Calculate Properties';
            app.TextArea2Label = uilabel(app.IdealGas);
            app.TextArea2Label.Text = 'Text Area2';
            app.IGResultsTextArea = uitextarea(app.IdealGas);
            app.SHVCLPropertiesTab = uitab(app.TabGroup);
            app.SHVCLPropertiesTab.Title = 'SHV/CL Properties';
            app.SHVCLPropertiesCalculatorLabel = uilabel(app.SHVCLPropertiesTab);
            app.SHVCLPropertiesCalculatorLabel.Text = 'SHV/CL Properties Calculator';
            app.SelectSubstanceLabel_3 = uilabel(app.SHVCLPropertiesTab);
            app.SelectSubstanceLabel_3.Text = 'Select Substance: ';
            app.SHVCLSubstanceDropDown = uidropdown(app.SHVCLPropertiesTab);
            app.SHVCLSubstanceDropDown.Items = {'Water', 'R-134a', 'Ammonia', 'Propane', 'CO2'};
            app.SHVCLSubstanceDropDown.Value = 'Water';
            app.TemperatureCLabel = uilabel(app.SHVCLPropertiesTab);
            app.TemperatureCLabel.Text = 'Temperature (°C):';
            app.SHVCLTempField = uieditfield(app.SHVCLPropertiesTab, 'numeric');
            app.PressurebarLabel = uilabel(app.SHVCLPropertiesTab);
            app.PressurebarLabel.Text = 'Pressure (bar):';
            app.SHVCLPressField = uieditfield(app.SHVCLPropertiesTab, 'numeric');
            app.CalculatePropertiesButton_3 = uibutton(app.SHVCLPropertiesTab, 'push');
            app.CalculatePropertiesButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculatePropertiesButton_3Pushed, true);
            app.CalculatePropertiesButton_3.Text = 'Calculate Properties';
            app.TextArea3Label = uilabel(app.SHVCLPropertiesTab);
            app.TextArea3Label.Text = 'Text Area3';
            app.SHVCLResultsTextArea = uitextarea(app.SHVCLPropertiesTab);
            app.SelectStateLabel = uilabel(app.SHVCLPropertiesTab);
            app.SelectStateLabel.Text = 'Select State: ';
            app.StateDropDown = uidropdown(app.SHVCLPropertiesTab);
            app.StateDropDown.Items = {'Superheated Vapor (SHV)', 'Compressed Liquid (CL)'};
            app.StateDropDown.Value = 'Superheated Vapor (SHV)';
            % Show the figure after all components are created
            app.ThermodynamicsPropertiesCalculator.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThermoCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ThermodynamicsPropertiesCalculator)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ThermodynamicsPropertiesCalculator)
        end
    end
end